classdef report_01 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        loadButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            filename = uigetfile('logo_csse.png');
            im=imread(filename);
            ax_src = uiaxes(app.UIFigure,'Position',[10, 250, 400, 200]);
            ax_src.Title.String = '画像表示';
            ax_src.XLabel.String = 'X';
            ax_src.YLabel.String = 'Y';
            imshow(im,'Parent',ax_src);
            com_button = uibutton(app.UIFigure,'Text','compute', 'Position',[409,100,100,23],'ButtonPushedFcn',@computeButtonPushed);
            function computeButtonPushed(src, event)
                ax_bin = uiaxes(app.UIFigure,'Position',[10, 10, 400, 200]);
                imgray=rgb2gray(im);
                BW=imbinarize(imgray);
                ax_bin.Title.String = '二値化';
                ax_bin.XLabel.String = 'X';
                ax_bin.YLabel.String = 'Y';
                imshow(BW,'Parent',ax_bin);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = report_01

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end